classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        featurequantizationCheckBox    matlab.ui.control.CheckBox
        formcodebookCheckBox           matlab.ui.control.CheckBox
        L2NNCheckBox                   matlab.ui.control.CheckBox
        SVMLinearCheckBox              matlab.ui.control.CheckBox
        Chi2NNCheckBox                 matlab.ui.control.CheckBox
        SVMLLCLinearCheckBox           matlab.ui.control.CheckBox
        SVMprecompLinearCheckBox       matlab.ui.control.CheckBox
        SVMinterCheckBox               matlab.ui.control.CheckBox
        SVMChi2CheckBox                matlab.ui.control.CheckBox
        splitsetsCheckBox              matlab.ui.control.CheckBox
        featextractionCheckBox         matlab.ui.control.CheckBox
        stepsEditFieldLabel            matlab.ui.control.Label
        stepsEditField                 matlab.ui.control.NumericEditField
        incrcodebookEditFieldLabel     matlab.ui.control.Label
        incrcodebookEditField          matlab.ui.control.NumericEditField
        CenterPanel                    matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
        viewfeaturesCheckBox           matlab.ui.control.CheckBox
        viewwordsCheckBox              matlab.ui.control.CheckBox
        viewconfmatCheckBox            matlab.ui.control.CheckBox
        viewresCheckBox                matlab.ui.control.CheckBox
        maxiterkmeanEditFieldLabel     matlab.ui.control.Label
        maxiterkmeanEditField          matlab.ui.control.NumericEditField
        featurecodebookEditFieldLabel  matlab.ui.control.Label
        featurecodebookEditField       matlab.ui.control.NumericEditField
        normbofhistEditFieldLabel      matlab.ui.control.Label
        normbofhistEditField           matlab.ui.control.NumericEditField
        trainimageEditFieldLabel       matlab.ui.control.Label
        trainimageEditField            matlab.ui.control.NumericEditField
        testimageEditFieldLabel        matlab.ui.control.Label
        testimageEditField             matlab.ui.control.NumericEditField
        ExecuteButton                  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            do_feat_extraction = app.featextractionCheckBox.Value;
            do_split_sets = app.splitsetsCheckBox.Value;
            
            do_form_codebook = app.formcodebookCheckBox.Value;
            do_feat_quantization = app.featextractionCheckBox.Value;
            
            % Classifier selection
            do_L2_NN_classification = app.L2NNCheckBox.Value;
            do_svm_linar_classification = app.SVMLinearCheckBox.Value;
            do_chi2_NN_classification = app.Chi2NNCheckBox.Value;
            do_svm_llc_linar_classification = app.SVMLLCLinearCheckBox.Value;
            do_svm_precomp_linear_classification = app.SVMprecompLinearCheckBox.Value;
            do_svm_inter_classification = app.SVMChi2CheckBox.Value;
            do_svm_chi2_classification = app.SVMChi2CheckBox.Value;
            
            % Initialization of variables
steps = app.stepsEditField.Value; % nr.passi di cui si incrementa il codeword
incr = app.incrcodebookEditField.Value; % incremento desiderato per il codeword
%nwords = zeros(steps,1);
list_method = {};

% Visualization options
visualize_feat = app.viewfeaturesCheckBox.Value;
visualize_words = app.viewwordsCheckBox;
visualize_confmat = app.viewconfmatCheckBox.Value;
visualize_res = app.viewresCheckBox.Value;
have_screen = isempty(getenv('DISPLAY'));

% BOW PARAMETERS
max_km_iters = app.maxiterkmeanEditField.Value; % maximum number of iterations for k-means
nfeat_codebook = app.featurecodebookEditField.Value; % number of descriptors used by k-means for the codebook generation
norm_bof_hist = app.normbofhistEditField.Value;

% number of images selected for training (e.g. 30 for Caltech-101)
num_train_img = app.trainimageEditField.Value;
% number of images selected for test (e.g. 50 for Caltech-101)
num_test_img = app.testimageEditField.Value;

L2_NN_acc = zeros(steps,1);
svm_linar_acc = zeros(steps,1);
chi2_NN_acc = zeros(steps,1);
svm_llc_linar_acc = zeros(steps,1);
svm_precomp_linea_acc = zeros(steps,1);
svm_inter_acc = zeros(steps,1);
svm_chi2_acc = zeros(steps,1);


            %% Previous code
            
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%  ICPR 2014 Tutorial                                                     %
%  Hands on Advanced Bag-of-Words Models for Visual Recognition           %
%                                                                         %
%  Instructors:                                                           %
%  L. Ballan     <lamberto.ballan@unifi.it>                               %
%  L. Seidenari  <lorenzo.seidenari@unifi.it>                             %
%                                                                         %
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


%   BOW pipeline: Image classification using bag-of-features

%   Part 1:  Load and quantize pre-computed image features                %
%   Part 2:  Represent images by histograms of quantized features         %
%   Part 3:  Classify images with nearest neighbor classifier             %

% clear;
% close all;
% clc

%% **** INITIALIZATION ****

% DATASET
% dataset_dir='4_ObjectCategories';
dataset_dir='12_CagliariMonuments';
% dataset_dir='10_CagliariMonuments';
%dataset_dir = '15_ObjectCategories';

% FEATURES extraction methods

% 'sift' for sparse features detection 
% (SIFT descriptors computed at Harris-Laplace keypoints)
% 'dsift' for dense features detection
% (SIFT descriptors computed at a grid of overlapped patches)

%desc_name = 'sift';
%desc_name = 'dsift';
%desc_name = 'msdsift';

desc_name = ["sift", "dsift"];


% PATHS
basepath = '..';
%basepath = '';
wdir = pwd;
libsvmpath = [wdir(1:end-6) fullfile('lib','libsvm-3.11','matlab')];
% add library folder to search path
addpath(libsvmpath)

% image file extension
file_ext='jpg';

% Create a new dataset split
file_split = 'split.mat';
if do_split_sets
    data = create_dataset_split_structure(fullfile(basepath, 'img', dataset_dir), num_train_img, num_test_img, file_ext);
    save(fullfile(basepath,'img',dataset_dir,file_split),'data');
else
    load(fullfile(basepath,'img',dataset_dir,file_split));
end
classes = {data.classname}; % create cell array of class name strings

sift_mods = size(desc_name,2);
for s_iter = 1:sift_mods
    
    % number of codewords (i.e. K for the k-means algorithm)
    nwords_codebook = 250;
    % Extract SIFT features fon training and test images
    if do_feat_extraction
        extract_sift_features(fullfile('..','img',dataset_dir),desc_name(s_iter));
    end   

    %% **** PART 1: quantize pre-computed image features ****

    [desc_train, desc_test] = load_precomputed_features(data, file_ext, desc_name(s_iter), dataset_dir, basepath);

    if (visualize_feat && have_screen)
        feature_visualizer(desc_train);
    end

    for iter = 1:steps % calculate steps

        % Build visual vocabulary using k-means
        fileName = strcat('vocab', desc_name(s_iter), int2str(nwords_codebook), '.mat');
        if do_form_codebook
            [VC] = visual_vocab_builder(data, desc_train, num_train_img, nfeat_codebook, nwords_codebook, max_km_iters);            
            save(fileName,'VC');
        else            
            if exist(fileName, 'file') == 2
                load(fileName,'VC');
            else
                [VC] = visual_vocab_builder(data, desc_train, num_train_img, nfeat_codebook, nwords_codebook, max_km_iters);            
                save(fileName,'VC');
            end      
        end
        

        % Quantization: assign each feature to the most representative visual word
        if do_feat_quantization
            [desc_train, desc_test] = feature_quantization_executor(desc_train, desc_test, VC);
        end

        %  Visualize visual words (i.e. clusters)
        if (visualize_words && have_screen)
            num_words = 10;
            visual_word_visualizer(num_words, desc_train);
        end


        %% **** PART 2: represent images with BOF histograms ****

        % Bag-of-Features image classification 
        [desc_train, desc_test] = compute_norm_histogram(VC, desc_train, desc_test, nwords_codebook, norm_bof_hist);

        %%%%LLC Coding
        if do_svm_llc_linar_classification
            for i=1:length(desc_train)
                disp(desc_train(i).imgfname);
                desc_train(i).llc = max(LLC_coding_appr(VC,desc_train(i).sift)); %max-pooling
                desc_train(i).llc=desc_train(i).llc/norm(desc_train(i).llc); %L2 normalization
            end
            for i=1:length(desc_test) 
                disp(desc_test(i).imgfname);
                desc_test(i).llc = max(LLC_coding_appr(VC,desc_test(i).sift));
                desc_test(i).llc=desc_test(i).llc/norm(desc_test(i).llc);
            end
        end
        %%%%end LLC coding


        %% **** PART 3: image classification ****

        % Concatenate bof-histograms into training and test matrices 
        bof_train=cat(1,desc_train.bof);
        bof_test=cat(1,desc_test.bof);
        if do_svm_llc_linar_classification
            llc_train = cat(1,desc_train.llc);
            llc_test = cat(1,desc_test.llc);
        end

        % Construct label Concatenate bof-histograms into training and test matrices 
        labels_train=cat(1,desc_train.class);
        labels_test=cat(1,desc_test.class);

        % L2 NN classification
        if do_L2_NN_classification
            [bof_l2lab] = L2_NN_classification(bof_test, bof_train, labels_test, labels_train);

            method_name='NN L2';
            % Compute classification accuracy
            [method_name, acc] = compute_accuracy(data,labels_test,bof_l2lab,classes,method_name,desc_test,...
                              visualize_confmat & have_screen,... 
                              visualize_res & have_screen);

            L2_NN_acc(iter) = acc;    
        end

        % LINEAR SVM
        if do_svm_linar_classification
            [svm_lab] = SVM_linear_classification(bof_test, bof_train, labels_test, labels_train);

            method_name='SVM linear';
            % Compute classification accuracy
            [method_name, acc] = compute_accuracy(data,labels_test,svm_lab,classes,method_name,desc_test,...
                              visualize_confmat & have_screen,... 
                              visualize_res & have_screen);

            SVM_linar_acc(iter) = acc;
        end

        % CHI2 NN classification
        if do_chi2_NN_classification
            [bof_chi2lab] = CHI2_NN_classification(bof_test, bof_train, labels_test, labels_train);

            method_name='NN Chi-2';
            % Compute classification accuracy
            [method_name, acc] = compute_accuracy(data,labels_test,bof_chi2lab,classes,method_name,desc_test,...
                              visualize_confmat & have_screen,... 
                              visualize_res & have_screen);

            chi2_NN_acc(iter) = acc;
        end

        % LLC LINEAR SVM
        if do_svm_llc_linar_classification
            [svm_llc_lab] = SVM_LLC_linar_classification(labels_test, llc_test, labels_train, llc_train);

            method_name='llc+max-pooling';
            [method_name, acc] = compute_accuracy(data,labels_test,svm_llc_lab,classes,method_name,desc_test,...
                              visualize_confmat & have_screen,... 
                              visualize_res & have_screen);

            svm_llc_linar_acc(iter) = acc;
        end

        % SVM image classification with pre-computed LINEAR KERNELS    
        if do_svm_precomp_linear_classification
            [precomp_svm_lab] = SVM_PC_linear_classification(bof_train, bof_test, labels_test, labels_train);
            method_name='SVM precomp linear';
            % Compute classification accuracy
            [method_name, acc] = compute_accuracy(data,labels_test,precomp_svm_lab,classes,method_name,desc_test,...
                              visualize_confmat & have_screen,... 
                              visualize_res & have_screen);
            % result is the same??? must be!

            svm_precomp_linea_acc(iter) = acc;
        end

        % Non-linear SVM with the histogram intersection kernel
        if do_svm_inter_classification
            [precomp_ik_svm_lab,conf] = SVM_inter_classification(bof_train, bof_test, labels_train, labels_test);
            method_name='SVM IK';
            % Compute classification accuracy
            [method_name, acc] = compute_accuracy(data,labels_test,precomp_ik_svm_lab,classes,method_name,desc_test,...
                              visualize_confmat & have_screen,... 
                              visualize_res & have_screen);

            svm_inter_acc(iter) = acc;
        end

        % CHI-2 KERNEL (pre-compute kernel)
        if do_svm_chi2_classification    
            [precomp_chi2_svm_lab,conf] = SVM_CHI2_classification(bof_train, bof_test, labels_train, labels_test);
            method_name='SVM Chi2';
            % Compute classification accuracy
            [method_name,acc] = compute_accuracy(data,labels_test,precomp_chi2_svm_lab,classes,method_name,desc_test,...
                              visualize_confmat & have_screen,... 
                              visualize_res & have_screen);
            svm_chi2_acc(iter) = acc;
        end
        nwords(iter) = nwords_codebook;
        nwords_codebook = nwords_codebook + incr; %incremento del nr.parole
     end

    % Plot bar
    figure(s_iter);
    for i = 1:steps
        iterData = [chi2_NN_acc(i), svm_chi2_acc(i)];
        ax1 = subplot(1,steps,i);
        c = categorical({'NN-CHI2' 'SVM-CHI2'});
        b = bar(ax1,c,iterData);
        b.FaceColor = 'flat';
        b.CData(1,:) = [1,0,0];
        b.CData(2,:) = [0,1,0];
        ylim([0 1])
        titleIterBar = sprintf('%s - Accuracy, DIM = %d', desc_name(s_iter), nwords(i));
        title(titleIterBar)
    end
    
%     subplot(2,1,1);
%     ax1Pos = get(ax1, 'Position');

    T.Iterazioni = nwords';
    T.NNCHI2 = chi2_NN_acc;
    T.SVMCHI2 = svm_chi2_acc;
    timeTable = struct2table(T);
    figure(s_iter+2);
    
    % Get the table in string form.
TString = evalc('disp(timeTable)');
% Use TeX Markup for bold formatting and underscores.
TString = strrep(TString,'<strong>','\bf');
TString = strrep(TString,'</strong>','\rm');
TString = strrep(TString,'_','\_');
% Get a fixed-width font.
FixedWidth = get(0,'FixedWidthFontName');
% Output the table using the annotation command.
annotation(gcf,'Textbox','String',TString,'Interpreter','Tex',...
    'FontName',FixedWidth,'Units','Normalized','Position',[0 0 1 1]);
    
%     uitable('Data',timeTable{:,:},'ColumnName',timeTable.Properties.VariableNames, 'RowName',timeTable.Properties.RowNames,'Units', 'Normalized', 'Position', [0, 0, 1, 1]);  % NEW
%     set(ax1, 'Visible', 'Off')
    

end
% Create structure
% for i = 1:steps
%     E(i).type = 'chi2_NN';
%     E(i).acc = chi2_NN_acc(i);
%     E(i).vwords = nwords(i);
%     R(i).type = 'svm_inter';
%     R(i).acc = svm_inter_acc(i);
%     R(i).vwords = nwords(i);
%     W(i).type = 'svm chi2';
%     W(i).acc = svm_chi2_acc(i);
%     W(i).vwords = nwords(i);
% end

% Create cumulative table from structure
% T1 = struct2table(E);
% T2 = struct2table(R);
% T3 = struct2table(W);
% tableTotal = [T1; T2; T3];

% Sort table (Acc)
% tableTotal_sort = sortrows(tableTotal,acc);


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'NN';
            app.featurequantizationCheckBox = uicheckbox(app.LeftPanel);
            app.featurequantizationCheckBox.Text = 'feature quantization';
            app.featurequantizationCheckBox.Value = true;
            app.formcodebookCheckBox = uicheckbox(app.LeftPanel);
            app.formcodebookCheckBox.Text = 'form codebook ';
            app.L2NNCheckBox = uicheckbox(app.LeftPanel);
            app.L2NNCheckBox.Text = 'L2 NN';
            app.SVMLinearCheckBox = uicheckbox(app.LeftPanel);
            app.SVMLinearCheckBox.Text = 'SVM Linear';
            app.Chi2NNCheckBox = uicheckbox(app.LeftPanel);
            app.Chi2NNCheckBox.Text = 'Chi2 NN';
            app.Chi2NNCheckBox.Value = true;
            app.SVMLLCLinearCheckBox = uicheckbox(app.LeftPanel);
            app.SVMLLCLinearCheckBox.Text = 'SVM LLC Linear';
            app.SVMprecompLinearCheckBox = uicheckbox(app.LeftPanel);
            app.SVMprecompLinearCheckBox.Text = 'SVM precomp Linear';
            app.SVMinterCheckBox = uicheckbox(app.LeftPanel);
            app.SVMinterCheckBox.Text = 'SVM  inter';
            app.SVMChi2CheckBox = uicheckbox(app.LeftPanel);
            app.SVMChi2CheckBox.Text = 'SVM Chi2';
            app.SVMChi2CheckBox.Value = true;
            app.splitsetsCheckBox = uicheckbox(app.LeftPanel);
            app.splitsetsCheckBox.Text = 'split sets';
            app.featextractionCheckBox = uicheckbox(app.LeftPanel);
            app.featextractionCheckBox.Text = 'feat extraction';
            app.stepsEditFieldLabel = uilabel(app.LeftPanel);
            app.stepsEditFieldLabel.Text = 'steps';
            app.stepsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.stepsEditField.Value = 2;
            app.incrcodebookEditFieldLabel = uilabel(app.LeftPanel);
            app.incrcodebookEditFieldLabel.Text = 'incr.codebook';
            app.incrcodebookEditField = uieditfield(app.LeftPanel, 'numeric');
            app.incrcodebookEditField.Limits = [0 Inf];
            app.incrcodebookEditField.Value = 250;
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.viewfeaturesCheckBox = uicheckbox(app.RightPanel);
            app.viewfeaturesCheckBox.Text = 'view features';
            app.viewwordsCheckBox = uicheckbox(app.RightPanel);
            app.viewwordsCheckBox.Text = 'view words';
            app.viewconfmatCheckBox = uicheckbox(app.RightPanel);
            app.viewconfmatCheckBox.Text = 'view confmat';
            app.viewresCheckBox = uicheckbox(app.RightPanel);
            app.viewresCheckBox.Text = 'view res';
            app.maxiterkmeanEditFieldLabel = uilabel(app.RightPanel);
            app.maxiterkmeanEditFieldLabel.Text = 'max iter kmean';
            app.maxiterkmeanEditField = uieditfield(app.RightPanel, 'numeric');
            app.maxiterkmeanEditField.Value = 50;
            app.featurecodebookEditFieldLabel = uilabel(app.RightPanel);
            app.featurecodebookEditFieldLabel.Text = 'feature codebook';
            app.featurecodebookEditField = uieditfield(app.RightPanel, 'numeric');
            app.featurecodebookEditField.Limits = [0 Inf];
            app.featurecodebookEditField.Value = 60000;
            app.normbofhistEditFieldLabel = uilabel(app.RightPanel);
            app.normbofhistEditFieldLabel.Text = 'norm bof hist';
            app.normbofhistEditField = uieditfield(app.RightPanel, 'numeric');
            app.normbofhistEditField.Limits = [0 Inf];
            app.normbofhistEditField.Value = 1;
            app.trainimageEditFieldLabel = uilabel(app.RightPanel);
            app.trainimageEditFieldLabel.Text = 'train.image';
            app.trainimageEditField = uieditfield(app.RightPanel, 'numeric');
            app.trainimageEditField.Limits = [0 Inf];
            app.trainimageEditField.Value = 30;
            app.testimageEditFieldLabel = uilabel(app.RightPanel);
            app.testimageEditFieldLabel.Text = 'test image';
            app.testimageEditField = uieditfield(app.RightPanel, 'numeric');
            app.testimageEditField.Limits = [0 Inf];
            app.testimageEditField.Value = 20;
            app.ExecuteButton = uibutton(app.RightPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end